classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        pulsereconstructionTab         matlab.ui.container.Tab
        PulsereconstructionButton      matlab.ui.control.Button
        collectingdatalasingoffButton  matlab.ui.control.Button
        collectingdatalasingonButton   matlab.ui.control.Button
        takebackgrounddataButton       matlab.ui.control.Button
        StartCheckBox                  matlab.ui.control.CheckBox
        UIAxes                         matlab.ui.control.UIAxes
        Tab2                           matlab.ui.container.Tab
    end

 % select cameras

properties (Access = public)
    rep_rate  =10;
    num_shot=10;
    num_bgr=10;
    addr_cam        = 'FLASH.DIAG/CAMERA/OTR9FL2XTDS/';
end 

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: StartCheckBox, UIAxes
        function UIAxesButtonDown(app, event)
            % loop
% here we just show the image on the screen 
            flag=true
              
            jj=0
            while flag
                if app.StartCheckBox.Value
                    flag=true
                else
                    flag=false
                end
                jj=jj+1
               
                ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                img_sig(:,:,jj)     = ddd_read.data.val_val;
                I= contourf(app.UIAxes,img_sig(:,:,jj) ,'EdgeColor','none');
                pause(2/app.rep_rate)


            end


        end

        % Button pushed function: collectingdatalasingoffButton
        function collectingdatalasingoffButtonPushed(app, event)
            % collecting back ground image
            delete imag_shots_lasing_off.mat
            
       % take bgr
            for jj = 1:app.num_shot

                % read
                ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                img(:,:,jj)     = ddd_read.data.val_val;
                
                ts(jj)=ddd_read.timestamp;
                % compared it with last measurement
                if jj > 1
                    while ts(jj) == ts(jj-1)
                        ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                        img(:,:,jj)       = ddd_read.data.val_val;
                        display( [' - (', num2str(jj), ') same data ... wait ...']);
                        pause(1/app.rep_rate)
                    end
                end

                pause(1/app.rep_rate)

            end


            img_mean = squeeze(mean(img, 3));


            save('imag_shots_lasing_off.mat', 'img_mean');
            display(' - image data taken')

        end

        % Button pushed function: takebackgrounddataButton
        function takebackgrounddataButtonPushed(app, event)
            % collecting back ground image
            delete background_img.mat
            
        
            % take bgr
            for jj = 1:app.num_bgr

                % read
                ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);

                img_bgr(:,:,jj)     = ddd_read.data.val_val;
                t(jj)=ddd_read.timestamp;
                % compared it with last measurement
                 if jj > 1
                     while t(jj) == t(jj-1)
                         ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                         img_bgr(:,:,jj)       = ddd_read.data.val_val;
                         display([' - (', num2str(jj), ') same data ... wait ...']);
                         pause(1/app.rep_rate)
                     end
                 end

                pause(1./app.rep_rate)

            end

            % compute mean()
            if app.num_bgr == 1
                img_bgr_mean = squeeze(img_bgr);
            else
                img_bgr_mean = squeeze(mean(img_bgr, 3));
            end
            %Saved back ground file

            save('background_img.mat', 'img_bgr_mean');

            display(' - background data taken')


        end

        % Button pushed function: collectingdatalasingonButton
        function collectingdatalasingonButtonPushed(app, event)
            
               % collecting back ground image
            delete imag_shots_lasing_on.mat
          
            % take bgr
            for jj = 1:app.num_shot

                % read
                ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                img(:,:,jj)     = ddd_read.data.val_val;
                
                ts(jj)=ddd_read.timestamp;
                % compared it with last measurement
                if jj > 1
                    while ts(jj) == ts(jj-1)
                        ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                        img(:,:,jj)       = ddd_read.data.val_val;
                        display( [' - (', num2str(jj), ') same data ... wait ...']);
                        pause(1/app.rep_rate)
                    end
                end

                pause(1/app.rep_rate)

            end


            img_mean = squeeze(mean(img, 3));


            save('imag_shots_lasing_on.mat', 'img_mean', 'img');
            display(' - image data taken')


        end

        % Button pushed function: PulsereconstructionButton
        function PulsereconstructionButtonPushed(app, event)
            Bakg=load('background_img.mat');
            imag_lasing_on=load('imag_shots_lasing_on.mat');
            imag_lasing_off=load('imag_shots_lasing_off.mat');

             % calibration data
            load('/home/ttflinac/user/mflorian/PolariX/FL2/time_calibration/time_calib_9FL2XTDS.mat', 'timecal_fspixel', ...
                'timecal_fspixel_err', 'timecal_streak', 'time_res')
            load('/home/ttflinac/user/mflorian/PolariX/FL2/energy_calibration/erg_calib_9FL2XTDS.mat', 'ergcal', 'ergcal_err')
            display('data is loaded')


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
check tomorrow 

% analysis
img_filt    = img_sig;

num_sig     = size(img_sig, 3);
length_y    = size(img_sig, 1);
length_x    = size(img_sig, 2);
img_bgr_mean = squeeze(mean(img_bgr, 3));

x_com       = zeros([1, num_sig]);              y_com       = zeros([1, num_sig]);
x_var       = zeros([1, num_sig]);              y_var       = zeros([1, num_sig]);
x_fwhm      = zeros([1, num_sig]);              y_fwhm      = zeros([1, num_sig]);
x_profile   = zeros([num_sig, length_x]);       y_profile   = zeros([num_sig, length_y]);
y=1:length_y;
%%
for jj = 1:num_sig
    
   tmp_img             = hlc_clean_image( squeeze(img_sig(:,:,jj)) - img_bgr_mean );
    %tmp_img             = get_ROI(squeeze(img_sig(:,:,jj)) - img_bgr_mean);
    img_filt(:,:,jj)    = medfilt2(tmp_img);
    
    % time
    tmp_profile         = mean(img_filt(:,:,jj));
%     tmp_profile(2010)=tmp_profile(2009); % remove hot pixel
%     tmp_profile         = medfilt1(tmp_profile, 1);
    [x_com(jj), x_var(jj), x_fwhm(jj), x_axis, x_profile(jj,:)]  = get_profile_stats(tmp_profile, calib_x);
    
    % erg
    tmp_profile         = mean(tmp_img, 2);
%     tmp_profile         = medfilt1(tmp_profile, 1);
    [y_com(jj), y_var(jj), y_fwhm(jj), y_axis, y_profile(jj,:)]  = get_profile_stats(tmp_profile', calib_y);


end


%% plot 1

fontSize        = 14;

erg_pos_good =  find(y_profile(end,:)>0); erg_pos_good = min(erg_pos_good):max(erg_pos_good);
time_pos_good = find(x_profile(end,:)>0); time_pos_good = min(time_pos_good):max(time_pos_good);
cent_energy_cr=zeros(num_sig, length_x);
slice_enrgy_spread=zeros(num_sig, length_x);
%%
%     figure; plot(slice_enrgy_spread_ave)
%%
for jj=1:num_sig
    for n=min(time_pos_good):max(time_pos_good)
              
                mu0=  find( img_filt(erg_pos_good,n,jj)==max(img_filt(erg_pos_good,n,jj)));
                [sigma, mu] = gaussfit( erg_pos_good, smoothdata(img_filt(erg_pos_good,n,jj)) , 0, erg_pos_good(mu0(1)));
                if isnan(sigma)
                else

                slice_enrgy_spread(jj, n)=sigma*calib_y;
                cent_energy_cr(jj, n)=mu*calib_y;
                end
                 
    end
end


%% jetting of stabilitty correction 
for jj=1: num_sig
        mass_center(jj)=find(x_profile(jj,:)==max(x_profile(jj,:)));
end
%%
figure(1044);hold on

sumes=zeros( length_x);
sumEcr=zeros( length_x);
sumCu=zeros( length_x);

for jj=1:num_sig
    subplot(3,3,1);hold on
    plot(time_axis(time_pos_good)-mean(time_axis(time_pos_good),'omitnan'),slice_enrgy_spread(jj,time_pos_good-mass_center(1)+mass_center(jj)))
    sumes(time_pos_good)=sumes(time_pos_good)+slice_enrgy_spread(jj,time_pos_good-mass_center(1)+mass_center(jj));
    subplot(3,3,2);hold on
    plot(time_axis(time_pos_good)-mean(time_axis(time_pos_good),'omitnan'),cent_energy_cr(jj,time_pos_good-mass_center(1)+mass_center(jj)))
    sumEcr(time_pos_good)=sumEcr(time_pos_good)+cent_energy_cr(jj,time_pos_good-mass_center(1)+mass_center(jj));
    subplot(3,3,3);hold on
    plot(time_axis(time_pos_good)-mean(time_axis(time_pos_good),'omitnan'),1e-3*charge_7FL2XTDS(jj)*1e-9*1e15*x_profile(jj,time_pos_good-mass_center(1)+mass_center(jj)))
    sumCu(time_pos_good)=sumCu(time_pos_good)+x_profile(jj,time_pos_good-mass_center(1)+mass_center(jj));
end
average_slice_energy_spread=sumes/num_sig;
aver_central_energy=sumEcr/num_sig;
aver_current=sumCu/num_sig*1e-3*charge_7FL2XTDS_mean*1e-9;

subplot(3,3,1)
plot(time_axis(time_pos_good)-mean(time_axis(time_pos_good),'omitnan'), average_slice_energy_spread(time_pos_good),'--k', 'LineWidth',3 )

set(gca, 'FontSize', fontSize,'YDir', 'normal')
xlabel('time t /fs', 'FontSize', fontSize)        
        ylabel('slice energy Sepread (Mev)', 'FontSize', fontSize)
subplot(3,3,2)
plot(time_axis(time_pos_good)-mean(time_axis(time_pos_good),'omitnan'), aver_central_energy(time_pos_good),'--k', 'LineWidth',3 )

set(gca, 'FontSize', fontSize,'YDir', 'normal')
xlabel('time t /fs', 'FontSize', fontSize)        
ylabel('\Delta energy cneter (Mev)', 'FontSize', fontSize)
subplot(3,3,3)
plot(time_axis(time_pos_good)-mean(time_axis(time_pos_good),'omitnan'), aver_current(time_pos_good),'--k', 'LineWidth',3 )

set(gca, 'FontSize', fontSize,'YDir', 'normal')
lim_y = get(gca, 'YLim'); lim_x = get(gca, 'XLim');
xlabel('time t /fs', 'FontSize', fontSize)        
ylabel('peak current /kA', 'FontSize', fontSize)
%%

% disp(' - ')
figure(1)
set(gcf, 'Position', [400, 1, 700, 700])

    subplot(2,4,1)
     imagesc(time_axis(time_pos_good)-mean(time_axis(time_pos_good),'omitnan'),...
            erg_axis(erg_pos_good), flipud(tmp2_img(time_pos_good,erg_pos_good)') )
        grid on
        title(['single image ', 'with SASE'], 'FontSize', fontSize, 'Interpreter', 'none')
        xlabel('time t /fs)', 'FontSize', fontSize)        
        ylabel('rel. energy deviation \delta / 10^{-3}' , 'FontSize', fontSize)
        set(gca, 'FontSize', fontSize,'YDir', 'normal')
        lim_y = get(gca, 'YLim'); lim_x = get(gca, 'XLim');
        
    subplot(2,4,2)       
        p1 = plot(y_profile(end,erg_pos_good), -erg_axis(erg_pos_good) );
        grid on
        title(timestamp1, 'FontSize', fontSize)
        xlabel('norm. erg density', 'FontSize', fontSize)        
        ylabel('rel. energy deviation \delta / 10^{-3}', 'FontSize', fontSize)
        legend([p1(1)], {['rms: ' num2str(y_var(end), '%5.2f'), ' \cdot 10^{-3}', 10, ...
                'FWHM: ', num2str(y_fwhm(end), '%5.2f'), ' \cdot 10^{-3}']}, ...
                'FontSize', fontSize-2)        
        set(gca, 'FontSize', fontSize, 'YDir', 'normal');%, 'Ylim', -lim_y)        
        
    subplot(2,4,3)
  
    yyaxis left
        p1 = plot(time_axis(time_pos_good)-mean(time_axis(time_pos_good),'omitnan'),...
            1e-3*charge_7FL2XTDS_mean*1e-9*1e15 * x_profile(end,time_pos_good));
        grid on
        title('current and energy spread', 'Interpreter', 'none')
        xlabel('time t /fs', 'FontSize', fontSize)        
        ylabel('peak current /kA', 'FontSize', fontSize)
%         legend([p1], {['rms mean: ' num2str(mean(x_var), '%5.2f'), ' fs', 10, ...
%                 'FWHM mean: ', num2str(mean(x_fwhm), '%5.2f'), ' fs', 10, ...
%                 'Time res: ' num2str((timeres_calib), '%5.1f'), ' fs']}, ...
%                 'FontSize', fontSize-2)      
        legend([p1], {['rms: ' num2str(x_var(end), '%5.2f'), ' fs', 10, ...
                        'FWHM: ', num2str(x_fwhm(end), '%5.2f'), ' fs', 10, ...
                        'Time res: ' num2str((timeres_calib), '%5.1f'), ' fs']}, ...
                        'FontSize', fontSize-2)    
        yyaxis right
        
           p2 = plot(time_axis(time_pos_good)-mean(time_axis(time_pos_good),'omitnan'), average_slice_energy_spread(time_pos_good))
             
           ylabel('energy spread (Mev)')
        legend([p2(1),p1(1)], { ['energy spread'],['current   ','rms: ' num2str(x_var(end), '%5.2f'), ' fs', 10, ...
                        'FWHM: ', num2str(x_fwhm(end), '%5.2f'), ' fs', 10, ...
                        'Time res: ' num2str((timeres_calib), '%5.1f'), ' fs']}, ...
                        'FontSize', fontSize-2)
                

        set(gca, 'FontSize', fontSize, 'Xlim', lim_x)           
                %'FWHM: ', num2str(mean(x_fwhm), '%5.2f'), ' fs', 10, ...
        %         

    subplot(2,4,4)
    MarkerSizz = 12;
    color1 = [0,0.4470, 0.7410];
    color2 = [0.85, 0.325, 0.098];
    yyaxis left
        p1 = plot(1:num_sig, x_var, 'kx', 'MarkerSize', MarkerSizz);
        hold on
        p2 = plot(1:num_sig, x_fwhm, 'x', 'Color', color1, 'MarkerSize', MarkerSizz);
        hold off
        ylabel('bunch length \sigma_t /fs', 'FontSize', fontSize)
        yyaxis right
           p3 = plot(1:num_sig, y_var, 'x', 'Color', color2, 'MarkerSize', MarkerSizz);
           ylabel('rms energy spread \sigma_E /10^{-3}', 'FontSize', fontSize)
        ax = gca;
        ax.YAxis(1).Color = 'k';
        ax.YAxis(2).Color = color2;
        grid on
        xlabel('#measurement', 'FontSize', fontSize)
        
        legend([p1(1), p2(1), p3(1)], {['rms bu length, mean: ', num2str(mean(x_var), '%5.2f'), '\pm', num2str(std(x_var), '%5.2f') ' fs'], ...
                                       ['fwhm bu length, mean: ', num2str(mean(x_fwhm), '%5.2f'), '\pm', num2str(std(x_fwhm), '%5.2f') ' fs'], ...
                               ['rms E spread, mean: ', num2str(mean(y_var), '%5.2f'), '\pm', num2str(std(y_var), '%5.2f') ' \cdot 10^{-3}']}, ...
                          'FontSize', fontSize-2)
%                       legend([p1], {['rms mean: ' num2str(mean(x_var), '%5.2f'), ' fs', 10, ...
%                 'FWHM mean: ', num2str(mean(x_fwhm), '%5.2f'), ' fs', 10, ...
%                 'Time res: ' num2str((timeres_calib), '%5.1f'), ' fs']}, ...
%                 'FontSize', fontSize-2)      
%         legend([p1(1), p2(1)], {['x in ', calib_x_unit], ...
%                                ['y in ', calib_y_unit]}, ...
%                           'FontSize', fontSize-2)
        set(gca, 'FontSize', fontSize) 


     % add elog printing button
    % filename=['/home/ttflinac/user/mflorian/PolariX/FL2/image_calibrated/image_', name_cam, '_', timestamp1, '.mat']
   %  text=[comment '\r\n', ...
        %     'data saved in:', filename]

     %comment='data saved at '
   %  uicontrol('String', 'Print', 'Callback', @(btn,~)printButtonCallbackDFS(btn, 'FL2 PolariX LPSD@OTR9FL2XTDS', text));
   %% save 

if (flag_save)
        save(['/home/ttflinac/user/mflorian/PolariX/FL2/image_calibrated/image_', name_cam, '_', timestamp1,'_', 'with_SASE' , '.mat'], ...
             'comment', 'timestamp', 'name_cam', 'img_bgr', 'img_sig', 'charge_7FL2XTDS', ...
             'timecal_fspixel', 'timecal_fspixel_err', 'timeres_calib', 'ergcal', 'ergcal_err', 'phase_TDS', 'tmp2_img', ...
              'x_com', 'x_var', 'x_fwhm', 'x_axis', 'x_profile',...
              'y_com', 'y_var', 'y_fwhm', 'y_axis', 'y_profile');
        %display_message([' - data saved']);
end
%%%%%%%%%%%%%%%%%%%%%


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.pulsereconstructionTab = uitab(app.TabGroup);
            app.pulsereconstructionTab.Title = 'pulse reconstruction';
            app.UIAxes = uiaxes(app.pulsereconstructionTab);
            title(app.UIAxes, 'image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.StartCheckBox = uicheckbox(app.pulsereconstructionTab);
            app.StartCheckBox.ValueChangedFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.StartCheckBox.Text = 'Start ';
            app.takebackgrounddataButton = uibutton(app.pulsereconstructionTab, 'push');
            app.takebackgrounddataButton.ButtonPushedFcn = createCallbackFcn(app, @takebackgrounddataButtonPushed, true);
            app.takebackgrounddataButton.Text = 'take background data';
            app.collectingdatalasingonButton = uibutton(app.pulsereconstructionTab, 'push');
            app.collectingdatalasingonButton.ButtonPushedFcn = createCallbackFcn(app, @collectingdatalasingonButtonPushed, true);
            app.collectingdatalasingonButton.Text = 'collecting data lasing on';
            app.collectingdatalasingoffButton = uibutton(app.pulsereconstructionTab, 'push');
            app.collectingdatalasingoffButton.ButtonPushedFcn = createCallbackFcn(app, @collectingdatalasingoffButtonPushed, true);
            app.collectingdatalasingoffButton.Text = 'collecting data lasing off';
            app.PulsereconstructionButton = uibutton(app.pulsereconstructionTab, 'push');
            app.PulsereconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @PulsereconstructionButtonPushed, true);
            app.PulsereconstructionButton.Text = 'Pulse reconstruction ';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end