classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        TtimeandEnergycollibrationTab  matlab.ui.container.Tab
        StartCheckBox_2                matlab.ui.control.CheckBox
        TimecollibrationPanel          matlab.ui.container.Panel
        rightEditField                 matlab.ui.control.NumericEditField
        rightEditFieldLabel            matlab.ui.control.Label
        phaseSpinner                   matlab.ui.control.Spinner
        phaseSpinnerLabel              matlab.ui.control.Label
        leftEditField                  matlab.ui.control.NumericEditField
        leftEditFieldLabel             matlab.ui.control.Label
        DotimecollibrationButton       matlab.ui.control.Button
        EnergycollibrationPanel        matlab.ui.container.Panel
        downEditField                  matlab.ui.control.NumericEditField
        downEditFieldLabel             matlab.ui.control.Label
        currentSpinner                 matlab.ui.control.Spinner
        currentSpinnerLabel            matlab.ui.control.Label
        upEditField                    matlab.ui.control.NumericEditField
        upEditFieldLabel               matlab.ui.control.Label
        DoenergycollibrationButton     matlab.ui.control.Button
        DosinglemeasurmentButton       matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        pulsereconstructionTab         matlab.ui.container.Tab
        yrangeEditField                matlab.ui.control.EditField
        foronlinemeasurmentwritethecorrectrangeEditFieldLabel  matlab.ui.control.Label
        nshotsEditField                matlab.ui.control.NumericEditField
        nshotsEditFieldLabel           matlab.ui.control.Label
        analysingimageButton           matlab.ui.control.Button
        feedbackonphaseCheckBox        matlab.ui.control.CheckBox
        PulsereconstructionButton      matlab.ui.control.Button
        collectingdatalasingoffButton  matlab.ui.control.Button
        collectingdatalasingonButton   matlab.ui.control.Button
        takebackgrounddataButton       matlab.ui.control.Button
        StartCheckBox                  matlab.ui.control.CheckBox
        UIAxes                         matlab.ui.control.UIAxes
        onlinemeasurmentTab            matlab.ui.container.Tab
        STOPCheckBox                   matlab.ui.control.CheckBox
        STARTCheckBox                  matlab.ui.control.CheckBox
        UIAxes_5                       matlab.ui.control.UIAxes
        UIAxes_4                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
    end

 % select cameras

properties (Access = public)
    rep_rate  =10;
    
    num_bgr=10;
    addr_cam        = 'FLASH.DIAG/CAMERA/OTR9FL2XTDS/';
    addr_charge='FLASH.DIAG/TOROID/7FL2XTDS/CHARGE.FLASH2';
    addr_TDSphase='FLASH.RF/LLRF.CONTROLLER/CTRL.POLARIX/SP.PHASE';
    add_COFM='FLASH.DIAG/CAMERA/OTR9FL2XTDS/SPECTRUM.X.CM'
    add2='FLASH.DIAG/CAMERA/OTR9FL2XTDS/SPECTRUM.X.MEAN';
    add_phase='FLASH.RF/LLRF.CONTROLLER/CTRL.POLARIX/SP.PHASE'
    addr_dipol='TTF2.MAGNETS/DIPOLE/D4FL2XTDS/PS'
   
end 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.phaseSpinner.Value=getfield(doocsread(app.addr_TDSphase) , 'data')
            app.rightEditField.Value=app.phaseSpinner.Value+2
            app.leftEditField.Value=app.phaseSpinner.Value-2
            %%
            app.currentSpinner.Value=getfield(doocsread(app.addr_dipol) , 'data')
            app.upEditField.Value=app.currentSpinner.Value+2
            app.downEditField.Value=app.currentSpinner.Value-2

            num_shot=str2num(app.nshotsEditField.Value);

        end

        % Callback function: StartCheckBox, UIAxes, feedbackonphaseCheckBox
        function UIAxesButtonDown(app, event)
            % loop
            % here we just show the image on the screen
            if app.StartCheckBox.Value
                    flag=true;
            else flag=false;
            end

            if app.feedbackonphaseCheckBox.Value
                
                x1=getfield(doocsread(app.add_COFM), 'data')
                phase_XTDS=getfield(doocsread(app.add_phase), 'data');
                tem=doocswrite(app.add_phase, phase_XTDS-0.5)
                x2=getfield(doocsread(app.add_COFM), 'data')
                if x2-x1 >0
                    phase_feedback=1 ;% or -1
                elseif x2-x1 <0
                    phase_feedback=-1;
                end 
            end 


            jj=0
 
            while app.StartCheckBox.Value
%                 if app.StartCheckBox.Value
%                     flag=app.StartCheckBox.Value
%                 else
%                     flag=false
%                 end

                jj=jj+1

                ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                img_sig(:,:,jj)     = ddd_read.data.val_val;
    
                a=str2num(app.yrangeEditField.Value);
                I= contourf(app.UIAxes,img_sig(end:-1:a,:,jj) ,'EdgeColor','none');
                if mod(jj,5)==0
                    if app.feedbackonphaseCheckBox.Value
                        x=getfield(doocsread(app.add_COFM), 'data')
                        if mean(x) > 8
                            phase_XTDS=getfield(doocsread(app.add_phase), 'data');
                            tem=doocswrite(app.add_phase, phase_XTDS-phase_feedback)
                        end
                        if mean(x) < 5
                            phase_XTDS=getfield(doocsread(app.add_phase), 'data');
                            tem=doocswrite(app.add_phase, phase_XTDS+phase_feedback)
                        end
                    end
                end
                pause(2/app.rep_rate)

            end


        end

        % Button pushed function: takebackgrounddataButton
        function takebackgrounddataButtonPushed(app, event)
            % collecting back ground image
            delete background_img.mat
            
        
            % take bgr
            for jj = 1:app.num_bgr

                % read
                ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);

                img_bgr(:,:,jj)     = ddd_read.data.val_val;
                t(jj)=ddd_read.timestamp;
                
                % compared it with last measurement
                 if jj > 1
                     while t(jj) == t(jj-1)
                         ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                         img_bgr(:,:,jj)       = ddd_read.data.val_val;
                         display([' - (', num2str(jj), ') same data ... wait ...']);
                         pause(1/app.rep_rate)
                     end
                 end

                pause(1./app.rep_rate)

            end

            % compute mean()
            if app.num_bgr == 1
                img_bgr_mean = squeeze(img_bgr);
            else
                img_bgr_mean = squeeze(mean(img_bgr, 3));
            end
            %Saved back ground file

            save('background_img.mat', 'img_bgr_mean');

            display(' - background data taken')


        end

        % Button pushed function: collectingdatalasingoffButton
        function collectingdatalasingoffButtonPushed(app, event)
            % collecting image shot Lasing off
            delete imag_shots_lasing_off.mat
            
       % take bgr
            for jj = 1:app.num_shot

                % read
                ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                img(:,:,jj)     = ddd_read.data.val_val;
                
                ts(jj)=ddd_read.timestamp;
                charge(jj)=doocsread(app.addr_charge);
                % compared it with last measurement
                if jj > 1
                    while ts(jj) == ts(jj-1)
                        ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                        img(:,:,jj)       = ddd_read.data.val_val;
                        display( [' - (', num2str(jj), ') same data ... wait ...']);
                        pause(1/app.rep_rate)
                    end
                end

                pause(1/app.rep_rate)

            end


            img_mean = squeeze(mean(img, 3));


            save('imag_shots_lasing_off.mat', 'img_mean', 'img','ts', 'charge');
            display(' - image data taken')

        end

        % Button pushed function: collectingdatalasingonButton
        function collectingdatalasingonButtonPushed(app, event)
            
               % collecting image shot lasing on 
            delete imag_shots_lasing_on.mat
          
            % take bgr
            for jj = 1:app.num_shot

                % read
                ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                img(:,:,jj)     = ddd_read.data.val_val;
                charge(jj)=doocsread(app.addr_charge);
                ts(jj)=ddd_read.timestamp;
                % compared it with last measurement
                if jj > 1
                    while ts(jj) == ts(jj-1)
                        ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                        img(:,:,jj)       = ddd_read.data.val_val;
                        display( [' - (', num2str(jj), ') same data ... wait ...']);
                        pause(1/app.rep_rate)
                    end
                end

                pause(1/app.rep_rate)

            end


            img_mean = squeeze(mean(img, 3));


            save('imag_shots_lasing_on.mat', 'img_mean', 'img', 'ts', 'charge');
            display(' - image data taken')


        end

        % Button pushed function: analysingimageButton
        function analysingimageButtonPushed(app, event)
            %address=~/
            %cd %(address)
            filename=uigetfile;
            assignin('base', 'filename',  filename)
            imag_file=load(filename);
            load('/home/ttflinac/user/mflorian/PolariX/FL2/time_calibration/time_calib_9FL2XTDS.mat', 'timecal_fspixel', ...
                'timecal_fspixel_err', 'timecal_streak', 'time_res');
            load('/home/ttflinac/user/mflorian/PolariX/FL2/energy_calibration/erg_calib_9FL2XTDS.mat', 'ergcal', 'ergcal_err')
            display('data is loaded');


            fontSize        = 14;
            calib_x             = timecal_fspixel;
            calib_y             = ergcal;
            timeres_calib       = time_res;


            a=str2num(app.yrangeEditField.Value);
            img_sig    = imag_file.img(end:a, :, :);

            num_sig     = size(img_sig, 3);
            length_y    = size(img_sig, 1);
            length_x    = size(img_sig, 2);


            x_com   = zeros([1, num_sig]);   y_com   = zeros([1, num_sig]);
            x_var   = zeros([1, num_sig]);   y_var   = zeros([1, num_sig]);
            x_fwhm  = zeros([1, num_sig]);   y_fwhm  = zeros([1, num_sig]);
            x_profile= zeros([num_sig, length_x]);
            y_profile= zeros([num_sig, length_y]);
            y=1:length_y;

            for i=1:num_sig, charge_7FL2XTDS(i)=imag_file.charge(i).data; end
            charge_7FL2XTDS_mean=mean(charge_7FL2XTDS)  ; %V check it
            tmp2_img=squeeze(transpose(img_sig(:,:,end)));
            %   
            %%
            for jj = 1:num_sig

                tmp_img             = hlc_clean_image( squeeze(img_sig(:,:,jj)) );
                
                img_filt(:,:,jj)    = medfilt2(tmp_img);

                % time
                tmp_profile         = mean(img_filt(:,:,jj));
                
                [x_com(jj), x_var(jj), x_fwhm(jj), x_axis, x_profile(jj,:)]  = get_profile_stats(tmp_profile, calib_x);

                % erg
                tmp_profile         = mean(tmp_img, 2);
                %     tmp_profile         = medfilt1(tmp_profile, 1);
                [y_com(jj), y_var(jj), y_fwhm(jj), y_axis, y_profile(jj,:)]  = get_profile_stats(tmp_profile', calib_y);

            end
            tmp2_img=squeeze(transpose(img_filt(:,:,end)));
             %% plot 1

       erg_axis            = ergcal * ((1:length_y)-length_y/2);
       time_axis           = timecal_fspixel * ((1:length_x)-length_x/2);
       erg_pos_good =  find(y_profile(end,:)>0); erg_pos_good = min(erg_pos_good):max(erg_pos_good);
       time_pos_good = find(x_profile(end,:)>0); time_pos_good = min(time_pos_good):max(time_pos_good);
       cent_energy_cr=zeros(num_sig, length_x);
       slice_enrgy_spread=zeros(num_sig, length_x);
            %%
            
            for jj=1:num_sig
                for n=min(time_pos_good):max(time_pos_good)

                    mu0=  find( img_sig(erg_pos_good,n,jj)==max(img_sig(erg_pos_good,n,jj)));
                    [sigma, mu] = gaussfit( erg_pos_good, smoothdata(img_sig(erg_pos_good,n,jj)) , 0, erg_pos_good(mu0(1)));
                    if isnan(sigma)
                    else

                        slice_enrgy_spread(jj, n)=sigma*calib_y;
                        cent_energy_cr(jj, n)=mu*calib_y;
                    end

                end
            end
      

            %% jetting of stabilitty correction   ********************NOT applicable wait 
            for jj=1: num_sig
                ftemp=find(x_profile(jj,:)==max(x_profile(jj,:)));
                mass_center(jj)=ftemp(1);

            end

figure(10012)
    set(gcf, 'Position', [800, 1, 1500, 1600])

    subplot(2,2,1)
    imagesc(time_axis(time_pos_good)-mean(time_axis(time_pos_good),'omitnan'),...
        erg_axis(erg_pos_good), flipud(tmp2_img(time_pos_good,erg_pos_good)') )
    grid on
    title(['single image '], 'FontSize', fontSize, 'Interpreter', 'none')
    xlabel('time t /fs)', 'FontSize', fontSize)
    ylabel('rel. energy deviation \delta / 10^{-3}' , 'FontSize', fontSize)
    set(gca, 'FontSize', fontSize,'YDir', 'normal')
    lim_y = get(gca, 'YLim'); lim_x = get(gca, 'XLim');

    subplot(2,2,2)
    p1 = plot( time_axis(time_pos_good)-mean(time_axis(time_pos_good),'omitnan'),...
       smoothdata(cent_energy_cr(end,time_pos_good)));
    grid on
    title('energy deviation' ,'FontSize', fontSize)
    xlabel('norm. erg density', 'FontSize', fontSize)
    ylabel('central of energy \delta / 10^{-3}', 'FontSize', fontSize)
    legend([p1(1)], {['rms: ' num2str(y_var(end), '%5.2f'), ' \cdot 10^{-3}', 10, ...
        'FWHM: ', num2str(y_fwhm(end), '%5.2f'), ' \cdot 10^{-3}']}, ...
        'Location','northoutside','FontSize', fontSize-2)
    set(gca, 'FontSize', fontSize, 'YDir', 'normal');%, 'Ylim', -lim_y)

    subplot(2,2,3)

    yyaxis left
    p1 = plot(time_axis(time_pos_good)-mean(time_axis(time_pos_good),'omitnan'),...
        1e-3*charge_7FL2XTDS_mean*1e-9*1e15 * x_profile(end,time_pos_good));
    grid on
    title('current and energy spread', 'Interpreter', 'none')
    xlabel('time t /fs', 'FontSize', fontSize)
    ylabel('peak current /kA', 'FontSize', fontSize)
    %

    legend([p1], {['rms: ' num2str(x_var(end), '%5.2f'), ' fs', 10, ...
        'FWHM: ', num2str(x_fwhm(end), '%5.2f'), ' fs', 10, ...
        'Time res: ' num2str((timeres_calib), '%5.1f'), ' fs']}, ...
        'Location','northoutside','FontSize', fontSize-2)
    yyaxis right

    p2 = plot(time_axis(time_pos_good)-mean(time_axis(time_pos_good),'omitnan'), smoothdata(slice_enrgy_spread(end,time_pos_good)))


    %slice_enrgy_spread(:,time_pos_good)
    ylabel('energy spread (Mev)')
    legend([p2(1),p1(1)], { ['energy spread'],['current   ','rms: ' num2str(x_var(end), '%5.2f'), ' fs', 10, ...
        'FWHM: ', num2str(x_fwhm(end), '%5.2f'), ' fs', 10, ...
        'Time res: ' num2str((timeres_calib), '%5.1f'), ' fs']}, ...
        'FontSize', fontSize-2, 'Location','northoutside')


    set(gca, 'FontSize', fontSize, 'Xlim', lim_x)
    %
    %

    subplot(2,2,4)
    MarkerSizz = 12;
    color1 = [0,0.4470, 0.7410];
    color2 = [0.85, 0.325, 0.098];
    yyaxis left
    p1 = plot(1:num_sig, x_var, 'kx', 'MarkerSize', MarkerSizz);
    hold on
    p2 = plot(1:num_sig, x_fwhm, 'x', 'Color', color1, 'MarkerSize', MarkerSizz);
    hold off
    ylabel('bunch length \sigma_t /fs', 'FontSize', fontSize)
    yyaxis right
    p3 = plot(1:num_sig, y_var, 'x', 'Color', color2, 'MarkerSize', MarkerSizz);
    ylabel('rms energy spread \sigma_E /10^{-3}', 'FontSize', fontSize)
    ax = gca;
    ax.YAxis(1).Color = 'k';
    ax.YAxis(2).Color = color2;
    grid on
    xlabel('#measurement', 'FontSize', fontSize)

    legend([p1(1), p2(1), p3(1)], {['rms bu length, mean: ', num2str(mean(x_var), '%5.2f'), '\pm', num2str(std(x_var), '%5.2f') ' fs'], ...
        ['fwhm bu length, mean: ', num2str(mean(x_fwhm), '%5.2f'), '\pm', num2str(std(x_fwhm), '%5.2f') ' fs'], ...
        ['rms E spread, mean: ', num2str(mean(y_var), '%5.2f'), '\pm', num2str(std(y_var), '%5.2f') ' \cdot 10^{-3}']}, ...
        'Location','northoutside', 'FontSize', fontSize-2)

    set(gca, 'FontSize', fontSize)
 t=date;
Year=year(t);   
fulder_add=['/home/ttflinac/data/PolariX/', num2str(Year), '/'];
timestamp       = datestr(clock, 'yyyy-mm-ddTHHMMSS');
save([fulder_add, timestamp, '.mat'],'timestamp' ,  'imag_file',  'slice_enrgy_spread', 'cent_energy_cr', 'x_var', 'x_fwhm', 'y_var','ergcal', 'timecal_fspixel')
filename=[fulder_add, timestamp, '.mat']
display(' - data saved');
Title='LPS analysis'
text='NONE'
uicontrol('String', 'Print', 'Callback',  @(btn,~)printinlogbook(btn,filename, Title, text ));

        end

        % Button pushed function: PulsereconstructionButton
        function PulsereconstructionButtonPushed(app, event)
           close all
           % Bakg=load('background_img.mat');
            imag_lasing_on=load('imag_shots_lasing_on.mat');
            imag_lasing_off=load('imag_shots_lasing_off.mat');

             % calibration data
            load('/home/ttflinac/user/mflorian/PolariX/FL2/time_calibration/time_calib_9FL2XTDS.mat', 'timecal_fspixel', ...
                'timecal_fspixel_err', 'timecal_streak', 'time_res');
            load('/home/ttflinac/user/mflorian/PolariX/FL2/energy_calibration/erg_calib_9FL2XTDS.mat', 'ergcal', 'ergcal_err')
            display('data is loaded');

            %num_sig=app.num_shot
            a=str2num(app.yrangeEditField.Value);
            %img_sig    = imag_file.img(end:a, :, :);

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


% analysis
%img_bgr_mean=Bakg.img_bgr_mean;
% img_sig=imag_lasing_on.img;
% charge_7FL2XTDS_mean=mean(mag_lasing_on.charge.data)  ; %V check it
fontSize        = 14;
calib_x             = timecal_fspixel;


calib_y             = ergcal;

timeres_calib       = time_res;
%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
for on_off=0:3:3
    if on_off==0
        img_sig=imag_lasing_on.img(end:a, :, :);
        num_sig     = size(img_sig, 3);
        for i=1:num_sig, charge_7FL2XTDS(i)=imag_lasing_on.charge(i).data; end
        charge_7FL2XTDS_mean=mean(charge_7FL2XTDS)  ; %V check it

        text='lasing on'
    else
        img_sig=imag_lasing_off.img;
        %         charge_7FL2XTDS_mean=mean(imag_lasing_off.charge)  ; %V check it
        %         charge_7FL2XTDS=imag_lasing_off.charge;
        for i=1:num_sig, charge_7FL2XTDS(i)=imag_lasing_off.charge(i).data; end
        charge_7FL2XTDS_mean=mean(charge_7FL2XTDS)  ; %V check it
        text='lasing off'

    end


    img_filt    = img_sig;

    num_sig     = size(img_sig, 3);
    length_y    = size(img_sig, 1);
    length_x    = size(img_sig, 2);

    %img_bgr_mean = squeeze(mean(img_bgr, 3));

    x_com       = zeros([1, num_sig]);              y_com       = zeros([1, num_sig]);
    x_var       = zeros([1, num_sig]);              y_var       = zeros([1, num_sig]);
    x_fwhm      = zeros([1, num_sig]);              y_fwhm      = zeros([1, num_sig]);
    x_profile   = zeros([num_sig, length_x]);       y_profile   = zeros([num_sig, length_y]);
    y=1:length_y;
    %%
    for jj = 1:num_sig

        tmp_img             = hlc_clean_image( squeeze(img_sig(:,:,jj)) );
        %tmp_img             = get_ROI(squeeze(img_sig(:,:,jj)) - img_bgr_mean);
        img_filt(:,:,jj)    = medfilt2(tmp_img);

        % time
        tmp_profile         = mean(img_filt(:,:,jj));
        %     tmp_profile(2010)=tmp_profile(2009); % remove hot pixel
        %     tmp_profile         = medfilt1(tmp_profile, 1);
        [x_com(jj), x_var(jj), x_fwhm(jj), x_axis, x_profile(jj,:)]  = get_profile_stats(tmp_profile, calib_x);

        % erg
        tmp_profile         = mean(tmp_img, 2);
        %     tmp_profile         = medfilt1(tmp_profile, 1);
        [y_com(jj), y_var(jj), y_fwhm(jj), y_axis, y_profile(jj,:)]  = get_profile_stats(tmp_profile', calib_y);

    end
    tmp2_img=squeeze(transpose(img_filt(:,:,end)));

    %% plot 1

    erg_axis            = ergcal * ((1:length_y)-length_y/2);
    time_axis           = timecal_fspixel * ((1:length_x)-length_x/2);
    erg_pos_good =  find(y_profile(end,:)>0); erg_pos_good = min(erg_pos_good):max(erg_pos_good);
    time_pos_good = find(x_profile(end,:)>0); time_pos_good = min(time_pos_good):max(time_pos_good);
    cent_energy_cr=zeros(num_sig, length_x);
    slice_enrgy_spread=zeros(num_sig, length_x);
    %%
    %     figure; plot(slice_enrgy_spread_ave)
    %%
    for jj=1:num_sig
        for n=min(time_pos_good):max(time_pos_good)

            mu0=  find( img_filt(erg_pos_good,n,jj)==max(img_filt(erg_pos_good,n,jj)));
            [sigma, mu] = gaussfit( erg_pos_good, smoothdata(img_filt(erg_pos_good,n,jj)) , 0, erg_pos_good(mu0(1)));
            if isnan(sigma)
            else

                slice_enrgy_spread(jj, n)=sigma*calib_y;
                cent_energy_cr(jj, n)=mu*calib_y;
            end

        end
    end


    %% jetting of stabilitty correction
    for jj=1: num_sig
        ftemp=find(x_profile(jj,:)==max(x_profile(jj,:)));
        mass_center(jj)=ftemp(1);
        
    end
    %%
    figure(1044);hold on
    set(gcf, 'position', [100,1, 1500, 1000])
    sumes=zeros( 1,length_x);
    sumEcr=zeros( 1,length_x);
    sumCu=zeros( 1,length_x);

    for jj=1:num_sig
        subplot(3,3,1+on_off);hold on
        plot(time_axis(time_pos_good)-mean(time_axis(time_pos_good),'omitnan'),slice_enrgy_spread(jj,time_pos_good-mass_center(1)+mass_center(jj)))
        sumes(time_pos_good)=sumes(time_pos_good)+slice_enrgy_spread(jj,time_pos_good-mass_center(1)+mass_center(jj));
        subplot(3,3,2+on_off);hold on
        plot(time_axis(time_pos_good)-mean(time_axis(time_pos_good),'omitnan'),cent_energy_cr(jj,time_pos_good-mass_center(1)+mass_center(jj)))
        sumEcr(time_pos_good)=sumEcr(time_pos_good)+cent_energy_cr(jj,time_pos_good-mass_center(1)+mass_center(jj));
        subplot(3,3,3+on_off);hold on
        plot(time_axis(time_pos_good)-mean(time_axis(time_pos_good),'omitnan'),1e-3*charge_7FL2XTDS(jj)*1e-9*1e15*x_profile(jj,time_pos_good-mass_center(1)+mass_center(jj)))
        sumCu(time_pos_good)=sumCu(time_pos_good)+1e-3*charge_7FL2XTDS(jj)*1e-9*1e15*x_profile(jj,time_pos_good-mass_center(1)+mass_center(jj));
    end
    aver_slice_energy_spread=sumes/num_sig;
    aver_central_energy=sumEcr/num_sig;
    aver_current=sumCu/num_sig;

    subplot(3,3,1+on_off)
    plot(time_axis(time_pos_good)-mean(time_axis(time_pos_good),'omitnan'), aver_slice_energy_spread(time_pos_good),'--k', 'LineWidth',3 )

    set(gca, 'FontSize', fontSize,'YDir', 'normal')
    xlabel('time t /fs', 'FontSize', fontSize)
    ylabel('slice energy Sepread (Mev)', 'FontSize', fontSize)


    subplot(3,3,2+on_off)
    plot(time_axis(time_pos_good)-mean(time_axis(time_pos_good),'omitnan'), aver_central_energy(time_pos_good),'--k', 'LineWidth',3 )

    set(gca, 'FontSize', fontSize,'YDir', 'normal')
    xlabel('time t /fs', 'FontSize', fontSize)
    ylabel('\Delta energy cneter (Mev)', 'FontSize', fontSize)
    title(text, 'FontSize', fontSize+3)
    subplot(3,3,3+on_off)
    plot(time_axis(time_pos_good)-mean(time_axis(time_pos_good),'omitnan'), aver_current(time_pos_good),'--k', 'LineWidth',3 )

    set(gca, 'FontSize', fontSize,'YDir', 'normal')
    lim_y = get(gca, 'YLim'); lim_x = get(gca, 'XLim');
    xlabel('time t /fs', 'FontSize', fontSize)
    ylabel('peak current /kA', 'FontSize', fontSize)
    %%
    if on_off==0
        imag_lasing_on.aver.central_energy=aver_central_energy;
        imag_lasing_on.aver.current =aver_current;
        imag_lasing_on.aver.slice_energy_spread=aver_slice_energy_spread;
    else
        on_off=4;
        imag_lasing_off.aver.central_energy=aver_central_energy;
        imag_lasing_off.aver.current =aver_current;
        imag_lasing_off.aver.slice_energy_spread=aver_slice_energy_spread;

    end

    % disp(' - ')
    figure(1)
    set(gcf, 'Position', [800, 1, 1500, 1600])

    subplot(2,4,1+on_off)
    imagesc(time_axis(time_pos_good)-mean(time_axis(time_pos_good),'omitnan'),...
        erg_axis(erg_pos_good), flipud(tmp2_img(time_pos_good,erg_pos_good)') )
    grid on
    title(['single image ', text], 'FontSize', fontSize, 'Interpreter', 'none')
    xlabel('time t /fs)', 'FontSize', fontSize)
    ylabel('rel. energy deviation \delta / 10^{-3}' , 'FontSize', fontSize)
    set(gca, 'FontSize', fontSize,'YDir', 'normal')
    lim_y = get(gca, 'YLim'); lim_x = get(gca, 'XLim');

    subplot(2,4,2+on_off)
    p1 = plot(y_profile(end,erg_pos_good), -erg_axis(erg_pos_good) );
    grid on
    title(text, 'FontSize', fontSize)
    xlabel('norm. erg density', 'FontSize', fontSize)
    ylabel('rel. energy deviation \delta / 10^{-3}', 'FontSize', fontSize)
    legend([p1(1)], {['rms: ' num2str(y_var(end), '%5.2f'), ' \cdot 10^{-3}', 10, ...
        'FWHM: ', num2str(y_fwhm(end), '%5.2f'), ' \cdot 10^{-3}']}, ...
        'Location','northoutside','FontSize', fontSize-2)
    set(gca, 'FontSize', fontSize, 'YDir', 'normal');%, 'Ylim', -lim_y)

    subplot(2,4,3+on_off)

    yyaxis left
    p1 = plot(time_axis(time_pos_good)-mean(time_axis(time_pos_good),'omitnan'),...
        1e-3*charge_7FL2XTDS_mean*1e-9*1e15 * x_profile(end,time_pos_good));
    grid on
    title('current and energy spread', 'Interpreter', 'none')
    xlabel('time t /fs', 'FontSize', fontSize)
    ylabel('peak current /kA', 'FontSize', fontSize)
    %         legend([p1], {['rms mean: ' num2str(mean(x_var), '%5.2f'), ' fs', 10, ...
    %                 'FWHM mean: ', num2str(mean(x_fwhm), '%5.2f'), ' fs', 10, ...
    %                 'Time res: ' num2str((timeres_calib), '%5.1f'), ' fs']}, ...
    %                 'FontSize', fontSize-2)
    legend([p1], {['rms: ' num2str(x_var(end), '%5.2f'), ' fs', 10, ...
        'FWHM: ', num2str(x_fwhm(end), '%5.2f'), ' fs', 10, ...
        'Time res: ' num2str((timeres_calib), '%5.1f'), ' fs']}, ...
        'Location','northoutside','FontSize', fontSize-2)
    yyaxis right

    p2 = plot(time_axis(time_pos_good)-mean(time_axis(time_pos_good),'omitnan'), aver_slice_energy_spread(time_pos_good))

    ylabel('energy spread (Mev)')
    legend([p2(1),p1(1)], { ['energy spread'],['current   ','rms: ' num2str(x_var(end), '%5.2f'), ' fs', 10, ...
        'FWHM: ', num2str(x_fwhm(end), '%5.2f'), ' fs', 10, ...
        'Time res: ' num2str((timeres_calib), '%5.1f'), ' fs']}, ...
        'FontSize', fontSize-2, 'Location','northoutside')


    set(gca, 'FontSize', fontSize, 'Xlim', lim_x)
    %'FWHM: ', num2str(mean(x_fwhm), '%5.2f'), ' fs', 10, ...
    %

    subplot(2,4,4+on_off)
    MarkerSizz = 12;
    color1 = [0,0.4470, 0.7410];
    color2 = [0.85, 0.325, 0.098];
    yyaxis left
    p1 = plot(1:num_sig, x_var, 'kx', 'MarkerSize', MarkerSizz);
    hold on
    p2 = plot(1:num_sig, x_fwhm, 'x', 'Color', color1, 'MarkerSize', MarkerSizz);
    hold off
    ylabel('bunch length \sigma_t /fs', 'FontSize', fontSize)
    yyaxis right
    p3 = plot(1:num_sig, y_var, 'x', 'Color', color2, 'MarkerSize', MarkerSizz);
    ylabel('rms energy spread \sigma_E /10^{-3}', 'FontSize', fontSize)
    ax = gca;
    ax.YAxis(1).Color = 'k';
    ax.YAxis(2).Color = color2;
    grid on
    xlabel('#measurement', 'FontSize', fontSize)

    legend([p1(1), p2(1), p3(1)], {['rms bu length, mean: ', num2str(mean(x_var), '%5.2f'), '\pm', num2str(std(x_var), '%5.2f') ' fs'], ...
        ['fwhm bu length, mean: ', num2str(mean(x_fwhm), '%5.2f'), '\pm', num2str(std(x_fwhm), '%5.2f') ' fs'], ...
        ['rms E spread, mean: ', num2str(mean(y_var), '%5.2f'), '\pm', num2str(std(y_var), '%5.2f') ' \cdot 10^{-3}']}, ...
        'Location','northoutside', 'FontSize', fontSize-2)

    set(gca, 'FontSize', fontSize)


end

%% Reconstruction


mass_center_on=find(imag_lasing_on.aver.current==max(imag_lasing_on.aver.current));
mass_center_off=find(imag_lasing_off.aver.current==max(imag_lasing_off.aver.current));


power=zeros( length_x,1);

power= imag_lasing_off.aver.central_energy(time_pos_good).* imag_lasing_off.aver.current(time_pos_good) - ...
    imag_lasing_on.aver.central_energy(time_pos_good-mass_center_off+mass_center_on).* imag_lasing_on.aver.current(time_pos_good-mass_center_off+mass_center_on);
% power=aver_central_energy_lasingoff(time_pos_good).* aver_current_lasingoff(time_pos_good) - ...
%         aver_central_energy_lasingon(time_pos_good).* aver_current_lasingoff(time_pos_good);
power2=(imag_lasing_off.aver.central_energy(time_pos_good)- ...
    imag_lasing_on.aver.central_energy(time_pos_good-mass_center_off+mass_center_on)).* imag_lasing_on.aver.current(time_pos_good-mass_center_off+mass_center_on);
%% save
timeCallib=load('/home/ttflinac/user/mflorian/PolariX/FL2/time_calibration/time_calib_9FL2XTDS.mat');
energyCalib=load('/home/ttflinac/user/mflorian/PolariX/FL2/energy_calibration/erg_calib_9FL2XTDS.mat');
t=date;
Year=year(t);
fulder_add=['/home/ttflinac/data/PolariX/', num2str(Year), '/'];
timestamp       = datestr(clock, 'yyyy-mm-ddTHHMMSS');
save([fulder_add, timestamp, '.mat'],'timestamp' ,  'img_bgr_mean',  'imag_lasing_on', 'imag_lasing_off','energyCalib', 'timeCallib', 'power')

display(' - data saved');
%%%%%%%%%%%%%%%%%%%%%
%%


figure(1044)
subplot(3,3,7)
plot(time_axis(time_pos_good)-mean(time_axis(time_pos_good),'omitnan'), power(:), '-k', 'LineWidth',3 )
hold on
plot(time_axis(time_pos_good)-mean(time_axis(time_pos_good),'omitnan'), power2(:), '-b', 'LineWidth',3 )
xlabel('time t /fs', 'FontSize', fontSize)
ylabel('peak power (GW)', 'FontSize', fontSize)
set(gca, 'FontSize', fontSize,'YDir', 'normal')

subplot(3,3,8)
hold on
p1=plot(time_axis(time_pos_good)-mean(time_axis(time_pos_good),'omitnan'), imag_lasing_off.aver.central_energy(time_pos_good), '--')
p2=plot(time_axis(time_pos_good)-mean(time_axis(time_pos_good),'omitnan'), imag_lasing_on.aver.central_energy(time_pos_good-mass_center_off+mass_center_on), '--')

set(gca, 'FontSize', fontSize,'YDir', 'normal')
xlabel('time t /fs', 'FontSize', fontSize)
ylabel('\Delta energy cneter (Mev)', 'FontSize', fontSize)

legend([p1(1), p2(1)],{'lassing off', 'lasing on'}, 'FontSize', fontSize-2, 'Location','northoutside', 'Orientation','horizontal')
figure(1044)
subplot(3,3,9)

hold on
p1=plot(time_axis(time_pos_good)-mean(time_axis(time_pos_good),'omitnan'), imag_lasing_off.aver.current(time_pos_good), '--')
p2=plot(time_axis(time_pos_good)-mean(time_axis(time_pos_good),'omitnan'), imag_lasing_on.aver.current(time_pos_good-mass_center_off+mass_center_on), '--')
xlabel('time t /fs', 'FontSize', fontSize)
ylabel('Current(KA)', 'FontSize', fontSize)
set(gca, 'FontSize', fontSize,'YDir', 'normal')
legend([p1(1), p2(1)],{'lassing off', 'lasing on'},'FontSize', fontSize-2, 'Location','northoutside', 'Orientation','horizontal')


filename=[fulder_add, timestamp, '.mat']
Title='Pulse Recunstruction'
text='NONE'
uicontrol('String', 'Print', 'Callback', @(btn,~)printinlogbook(btn,filename, Title, text ));


figure(1)


Title='Pulse Recunstruction'
text='NONE'
uicontrol('String', 'Print', 'Callback',  @(btn,~)printinlogbook(btn,filename, Title, text ));
        end

        % Callback function: StartCheckBox_2, UIAxes_2
        function UIAxes_2ButtonDown(app, event)


            % loop
            % here we just show the image on the screen
            flag=true

            jj=0
            while flag
                if app.StartCheckBox_2.Value
                    flag=true
                else
                    flag=false
                end
                jj=jj+1

                ddd_read            = doocsread([app.addr_cam, 'IMAGE_EXT_ZMQ']);
                img_sig(:,:,jj)     = ddd_read.data.val_val;

                I= contourf(app.UIAxes_2,img_sig(:,:,jj) ,'EdgeColor','none');
                pause(2/app.rep_rate)


            end


        end

        % Value changing function: phaseSpinner
        function phaseSpinnerValueChanging(app, event)
            
           
            app.rightEditField.Value=app.phaseSpinner.Value+2
            app.leftEditField.Value=app.phaseSpinner.Value-2     
            tem=doocswrite(app.add_phase, app.phaseSpinner.Value-1)
            display(app.phaseSpinner.Value)
        end

        % Value changed function: currentSpinner
        function currentSpinnerValueChanged(app, event)
            %value = app.currentSpinner.Value;
            %tem=doocswrite(app.add_dipol, app.currentSpinner.Value)
            app.upEditField.Value=app.currentSpinner.Value-1
            app.downEditField.Value=app.currentSpinner.Value+1

        end

        % Button pushed function: DotimecollibrationButton
        function DotimecollibrationButtonPushed(app, event)
            a=app.leftEditField.Value
            b=app.rightEditField.Value  %app.yrangeEditField.Value
            num_sig=app.num_shot
            get_timecolibration(a, b, num_sig)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TtimeandEnergycollibrationTab = uitab(app.TabGroup);
            app.TtimeandEnergycollibrationTab.Title = 'Ttime and Energy collibration ';
            app.UIAxes_2 = uiaxes(app.TtimeandEnergycollibrationTab);
            title(app.UIAxes_2, 'image')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.DosinglemeasurmentButton = uibutton(app.TtimeandEnergycollibrationTab, 'push');
            app.DosinglemeasurmentButton.Text = 'Do single measurment';
            app.EnergycollibrationPanel = uipanel(app.TtimeandEnergycollibrationTab);
            app.EnergycollibrationPanel.Title = 'Energy collibration';
            app.DoenergycollibrationButton = uibutton(app.EnergycollibrationPanel, 'push');
            app.DoenergycollibrationButton.Text = 'Do energy collibration';
            app.upEditFieldLabel = uilabel(app.EnergycollibrationPanel);
            app.upEditFieldLabel.Text = 'up';
            app.upEditField = uieditfield(app.EnergycollibrationPanel, 'numeric');
            app.currentSpinnerLabel = uilabel(app.EnergycollibrationPanel);
            app.currentSpinnerLabel.Text = 'current';
            app.currentSpinner = uispinner(app.EnergycollibrationPanel);
            app.currentSpinner.ValueChangedFcn = createCallbackFcn(app, @currentSpinnerValueChanged, true);
            app.downEditFieldLabel = uilabel(app.EnergycollibrationPanel);
            app.downEditFieldLabel.Text = 'down';
            app.downEditField = uieditfield(app.EnergycollibrationPanel, 'numeric');
            app.TimecollibrationPanel = uipanel(app.TtimeandEnergycollibrationTab);
            app.TimecollibrationPanel.Title = 'Time collibration';
            app.DotimecollibrationButton = uibutton(app.TimecollibrationPanel, 'push');
            app.DotimecollibrationButton.ButtonPushedFcn = createCallbackFcn(app, @DotimecollibrationButtonPushed, true);
            app.DotimecollibrationButton.Text = 'Do time collibration';
            app.leftEditFieldLabel = uilabel(app.TimecollibrationPanel);
            app.leftEditFieldLabel.Text = 'left';
            app.leftEditField = uieditfield(app.TimecollibrationPanel, 'numeric');
            app.phaseSpinnerLabel = uilabel(app.TimecollibrationPanel);
            app.phaseSpinnerLabel.Text = 'phase';
            app.phaseSpinner = uispinner(app.TimecollibrationPanel);
            app.phaseSpinner.ValueChangingFcn = createCallbackFcn(app, @phaseSpinnerValueChanging, true);
            app.phaseSpinner.Value = 120;
            app.rightEditFieldLabel = uilabel(app.TimecollibrationPanel);
            app.rightEditFieldLabel.Text = 'right';
            app.rightEditField = uieditfield(app.TimecollibrationPanel, 'numeric');
            app.StartCheckBox_2 = uicheckbox(app.TtimeandEnergycollibrationTab);
            app.StartCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.StartCheckBox_2.Text = 'Start ';
            app.pulsereconstructionTab = uitab(app.TabGroup);
            app.pulsereconstructionTab.Title = 'pulse reconstruction';
            app.UIAxes = uiaxes(app.pulsereconstructionTab);
            title(app.UIAxes, 'image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.StartCheckBox = uicheckbox(app.pulsereconstructionTab);
            app.StartCheckBox.ValueChangedFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.StartCheckBox.Text = 'Start ';
            app.takebackgrounddataButton = uibutton(app.pulsereconstructionTab, 'push');
            app.takebackgrounddataButton.ButtonPushedFcn = createCallbackFcn(app, @takebackgrounddataButtonPushed, true);
            app.takebackgrounddataButton.Text = 'take background data';
            app.collectingdatalasingonButton = uibutton(app.pulsereconstructionTab, 'push');
            app.collectingdatalasingonButton.ButtonPushedFcn = createCallbackFcn(app, @collectingdatalasingonButtonPushed, true);
            app.collectingdatalasingonButton.Text = 'collecting data lasing on';
            app.collectingdatalasingoffButton = uibutton(app.pulsereconstructionTab, 'push');
            app.collectingdatalasingoffButton.ButtonPushedFcn = createCallbackFcn(app, @collectingdatalasingoffButtonPushed, true);
            app.collectingdatalasingoffButton.Text = 'collecting data lasing off';
            app.PulsereconstructionButton = uibutton(app.pulsereconstructionTab, 'push');
            app.PulsereconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @PulsereconstructionButtonPushed, true);
            app.PulsereconstructionButton.Text = 'Pulse reconstruction ';
            app.feedbackonphaseCheckBox = uicheckbox(app.pulsereconstructionTab);
            app.feedbackonphaseCheckBox.ValueChangedFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.feedbackonphaseCheckBox.Text = 'feedback on phase';
            app.analysingimageButton = uibutton(app.pulsereconstructionTab, 'push');
            app.analysingimageButton.ButtonPushedFcn = createCallbackFcn(app, @analysingimageButtonPushed, true);
            app.analysingimageButton.Text = 'analysing image ';
            app.nshotsEditFieldLabel = uilabel(app.pulsereconstructionTab);
            app.nshotsEditFieldLabel.Text = 'n shots';
            app.nshotsEditField = uieditfield(app.pulsereconstructionTab, 'numeric');
            app.nshotsEditField.Value = 10;
            app.foronlinemeasurmentwritethecorrectrangeEditFieldLabel = uilabel(app.pulsereconstructionTab);
            app.foronlinemeasurmentwritethecorrectrangeEditFieldLabel.Text = 'for online measurment write the correct  range ';
            app.yrangeEditField = uieditfield(app.pulsereconstructionTab, 'text');
            app.yrangeEditField.Value = '1000';
            app.onlinemeasurmentTab = uitab(app.TabGroup);
            app.onlinemeasurmentTab.Title = 'on line measurment ';
            app.UIAxes_3 = uiaxes(app.onlinemeasurmentTab);
            title(app.UIAxes_3, 'image')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.onlinemeasurmentTab);
            title(app.UIAxes_4, 'current and energy spread')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.onlinemeasurmentTab);
            title(app.UIAxes_5, 'chirp calculation')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.STARTCheckBox = uicheckbox(app.onlinemeasurmentTab);
            app.STARTCheckBox.Text = 'START';
            app.STOPCheckBox = uicheckbox(app.onlinemeasurmentTab);
            app.STOPCheckBox.Text = 'STOP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end